classdef GeneralLinearModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainPanel                       matlab.ui.container.Panel
        MainGridLayout                  matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        DesignMatrixTab                 matlab.ui.container.Tab
        DesignMatrixTabGridLayout       matlab.ui.container.GridLayout
        ModelSpecValue                  matlab.ui.control.Label
        ModelSpecLabel                  matlab.ui.control.Label
        DesignMatrixPanel               matlab.ui.container.Panel
        DesignMatrixGridLayout          matlab.ui.container.GridLayout
        DesignMatrixInterceptEditField  matlab.ui.control.EditField
        DesignMatrixVarTypeLabel        matlab.ui.control.Label
        DesignMatrixVarNameLabel        matlab.ui.control.Label
        DesignMatrixNumEVsInput         matlab.ui.control.Spinner
        NumberofpredictorsLabel         matlab.ui.control.Label
        StatsTab                        matlab.ui.container.Tab
        StatsTabGridLayout              matlab.ui.container.GridLayout
        StatsPanel                      matlab.ui.container.Panel
        StatsGridLayout                 matlab.ui.container.GridLayout
        InterceptLabel                  matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
        FtestsSpinner                   matlab.ui.control.Spinner
        FtestsSpinnerLabel              matlab.ui.control.Label
        TcontrastsSpinner               matlab.ui.control.Spinner
        TcontrastsSpinnerLabel          matlab.ui.control.Label
        PostStatsTab                    matlab.ui.container.Tab
        PostStatsGridLayout             matlab.ui.container.GridLayout
        ThresholdingMethodPanel         matlab.ui.container.Panel
        ThresholdingMethodGridLayout    matlab.ui.container.GridLayout
        ClusterAlphaLabel               matlab.ui.control.Label
        ClusterAlphaEditField           matlab.ui.control.NumericEditField
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        ThresholdingMethodDropDown      matlab.ui.control.DropDown
        ChannelLocationsBrowseButton    matlab.ui.control.Button
        PrethresholdmaskingEditField    matlab.ui.control.EditField
        PrethresholdmaskingEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryPanel            matlab.ui.container.Panel
        OutputDirectoryGridLayout       matlab.ui.container.GridLayout
        OutputDirectoryValue            matlab.ui.control.EditField
        OutputDirectoryBrowseButton     matlab.ui.control.Button
        LoadingImage                    matlab.ui.control.Image
        AddProcessButton                matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        Objects
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Checks if an object exists
        function exists = ObjectExists(app, fname, i)
            % Check if object exists already
            if ~isfield(app.Objects, fname)
                exists = false;
            elseif length(app.Objects.(fname)) < i
                exists = false;
            elseif ~isfield(app.Objects.(fname)(i), 'Obj')
                exists = false;
            elseif isfield(app.Objects.(fname)(i), 'Obj') && isnumeric(app.Objects.(fname)(i).Obj)
                exists = false;
            elseif isfield(app.Objects.(fname)(i), 'Obj') && ~isvalid(app.Objects.(fname)(i).Obj)
                exists = false;
            else
                exists = true;
            end
        end
        % =========================================================
        function [DesMat, Levels] = GetVarDesMat(app, VarName, Type, Standardize)
            % Extract the values from the data table
            Values = app.State.Phenotype.(VarName);
            % If it is a numeric array, create a single vector
            switch Type
                case 'continuous'
                    % Standardize the value if needed
                    switch Standardize
                        case 'demean'
                            Values = Values - mean(Values, 'omitnan');
                        case 'z-score'
                            Values = nanzscore(Values);
                        otherwise
                            % Do nothing
                    end
                    DesMat = Values;
                    Levels = unique(Values);
                    if length(Levels) == 1 % If there is only one value in the array, it's no good.
                        DesMat = -1;
                        Levels = cell(0);
                    end
                case 'categorical'
                    DesMat = dummyvar(categorical(Values));
                    Levels = categories(categorical(Values));
                    if length(Levels) == 1
                        DesMat = -1;
                        Levels = cell(0);
                    else
                        DesMat = DesMat(:, 2:end); % The first column is the reference column
                        Levels = Levels(2:end);
                    end
            end
        end
        % =========================================================
        function [ModelSpec, PredNames] = GetModelSpec(app)
            ModelSpec = 'Y ~ 1';
            PredNames = {};
            for i = 1:length(app.State.Model.Predictors)
                switch app.State.Model.Predictors(i).Type
                    case 'continuous'
                        ModelSpec = [ModelSpec, ' + ', app.State.Model.Predictors(i).Name]; %#ok<AGROW> 
                        PredNames = [PredNames, {app.State.Model.Predictors(i).Name}]; %#ok<AGROW> 
                    case 'categorical'
                        for j = 1:length(app.State.Model.Predictors(i).Levels)
                            ModelSpec = [ModelSpec, ' + ', app.State.Model.Predictors(i).Name, '_', app.State.Model.Predictors(i).Levels{j}]; %#ok<AGROW> 
                            PredNames = [PredNames, {[app.State.Model.Predictors(i).Name, '_', app.State.Model.Predictors(i).Levels{j}]}]; %#ok<AGROW> 
                        end
                end
            end
        end
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, FileList)
            app.State = struct();
            app.State.isLoading = false;
            app.State.OuputDir = '';
            app.State.Input = FileList;
            app.State.Phenotype = readPhenotype(app.State.Input);
            app.State.Model.ModelSpec = 'Y ~ 1';
            app.State.Model.PredNames = cell(0);
            app.State.Model.Predictors.Name = '';
            app.State.Model.Predictors.Type = '';
            app.State.Model.Predictors.Standardize = 'demean';
            app.State.Model.Predictors.Levels = cell(0);
            app.State.Model.Predictors.DesMat = zeros(0);
            app.State.Model.Predictors(1) = [];
            app.State.Statistics.Ttests.Titles = cell(0);
            app.State.Statistics.Ttests.Contrasts = zeros(0, 1);
            app.State.Statistics.Ftests = zeros(0);
            app.State.PostStats.Mask = '';
            app.State.PostStats.Method = 'TFCE';
            app.State.PostStats.Alpha = 0.05;
            app.State.PostStats.ClusterAlpha = 0.05;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.RenderDesignMatrixModelSpec();
            app.RenderDesignMatrixGridLayout();
            app.RenderDesignMatrixSubjectInput();
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixModelSpec value
        function RenderDesignMatrixModelSpec(app)
            UpdateProperty(app, app.ModelSpecValue, 'Text', app.State.Model.ModelSpec);
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixGridLayout
        function RenderDesignMatrixGridLayout(app)
            % Create a row for each input
            app.DesignMatrixGridLayout.RowHeight = num2cell(ones(1, length(app.State.Input)+3).*24);
            % How many predictors?
            NumPredictors = length(app.State.Model.Predictors);
            % Create a column for each predictor (first 2 columns are fixed)
            app.DesignMatrixGridLayout.ColumnWidth = [{110, 50}, num2cell(ones(1, NumPredictors).*85)];
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixSubjectInput fields and their intercept input field
        function RenderDesignMatrixSubjectInput(app)
            % Render an input field for each input
            for i = 1:length(app.State.Input)
                % Render EditField
                if app.ObjectExists('InputName', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.InputName(i).Obj, 'Value', app.State.Input{i}.KeyVals.sub);
                else 
                    % Object does not exist, create
                    app.Objects.InputName(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                        'Value', app.State.Input{i}.KeyVals.sub, ...
                        'Editable', 'off');
                    app.Objects.InputName(i).Obj.Layout.Row = 3+i;
                    app.Objects.InputName(i).Obj.Layout.Column = 1;
                end
                % Render Intercept Value Fields
                if ~app.ObjectExists('Intercept', i)
                    % Object does not exist, create
                    app.Objects.Intercept(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                        'Value', '1', ...
                        'Editable', 'off');
                    app.Objects.Intercept(i).Obj.Layout.Row = 3+i;
                    app.Objects.Intercept(i).Obj.Layout.Column = 2;
                end
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarName inputs
        function RenderDesignMatrixVarNameInput(app)
            for i = 1:length(app.State.Model.Predictors)
                % Render VarNameDropDown
                if app.ObjectExists('VarName', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarName(i).Obj, 'Value', app.State.Model.Predictors(i).Name);
                else 
                    % Object does not exist, create
                    app.Objects.VarName(i).Obj = uidropdown(app.DesignMatrixGridLayout, ...
                        'FontSize', 10, ...
                        'Items', app.State.Phenotype.Properties.VariableNames(~ismember(app.State.Phenotype.Properties.VariableNames, {'participant_id'})), ...
                        'Value', app.State.Model.Predictors(i).Name, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarNameDropDownMenuChanged, true));
                    app.Objects.VarName(i).Obj.UserData.Index = i;
                    app.Objects.VarName(i).Obj.Layout.Row = 1;
                    app.Objects.VarName(i).Obj.Layout.Column = i+2;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            for i = length(app.Objects.VarName):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarName(i).Obj)
                app.Objects.VarName(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarType inputs
        function RenderDesignMatrixVarTypeInput(app)
            for i = 1:length(app.State.Model.Predictors)
                % Get the variable name
                VarName = app.State.Model.Predictors(i).Name;
                % Get the values from the data table
                Values = app.State.Phenotype.(VarName);
                % The var type can only be changed if it is numerical
                if isnumeric(Values)
                    Enable = 'on';
                else
                    Enable = 'off';
                end
                % Render VarTypeDropDown
                if app.ObjectExists('VarType', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarType(i).Obj, 'Value', app.State.Model.Predictors(i).Type);
                    UpdateProperty(app, app.Objects.VarType(i).Obj, 'Enable', Enable);
                    app.Objects.VarType(i).Obj.UserData.VarName = VarName;
                else 
                    % Object does not exist, create
                    app.Objects.VarType(i).Obj = uidropdown(app.DesignMatrixGridLayout, ...
                        'FontSize', 10, ...
                        'Items', {'continuous', 'categorical'}, ...
                        'Value', app.State.Model.Predictors(i).Type, ...
                        'Enable', Enable, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarTypeDropDownMenuChanged, true));
                    app.Objects.VarType(i).Obj.UserData.Index = i;
                    app.Objects.VarType(i).Obj.UserData.VarName = VarName;
                    app.Objects.VarType(i).Obj.Layout.Row = 2;
                    app.Objects.VarType(i).Obj.Layout.Column = i+2;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            for i = length(app.Objects.VarType):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarType(i).Obj)
                app.Objects.VarType(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarStandardize inputs
        function RenderDesignMatrixVarStandardizeInput(app)
            for i = 1:length(app.State.Model.Predictors)
                % Check what value it should be, and to enable the input or not
                switch app.State.Model.Predictors(i).Type
                    case 'continuous'
                        Value = app.State.Model.Predictors(i).Standardize;
                        Enable = 'on';
                    case 'categorical'
                        Value = 'n/a';
                        Enable = 'off';
                end
                % Render VarTypeDropDown
                if app.ObjectExists('VarStandardize', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarStandardize(i).Obj, 'Value', Value);
                    UpdateProperty(app, app.Objects.VarStandardize(i).Obj, 'Enable', Enable);
                else 
                    % Object does not exist, create
                    app.Objects.VarStandardize(i).Obj = uidropdown(app.DesignMatrixGridLayout, ...
                        'FontSize', 10, ...
                        'Items', {'n/a', 'none', 'demean', 'z-score'}, ...
                        'Value', Value, ...
                        'Enable', Enable, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarStandardizeDropDownMenuChanged, true));
                    app.Objects.VarStandardize(i).Obj.UserData.Index = i;
                    app.Objects.VarStandardize(i).Obj.Layout.Row = 3;
                    app.Objects.VarStandardize(i).Obj.Layout.Column = i+2;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            for i = length(app.Objects.VarStandardize):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarStandardize(i).Obj)
                app.Objects.VarStandardize(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrix Value inputs
        function RenderDesignMatrixValueInput(app, VarIdx)
            if VarIdx > 0
                % Get the variable name
                VarName = app.State.Model.Predictors(VarIdx).Name;
                % Get the values from the data table
                Values = app.State.Phenotype.(VarName);
                % Standardize the value if needed
                switch app.State.Model.Predictors(VarIdx).Standardize
                    case 'demean'
                        Values = Values - mean(Values, 'omitnan');
                    case 'z-score'
                        Values = nanzscore(Values);
                    otherwise
                        % Do nothing
                end
                % For each input, create an input field
                for i = 1:length(app.State.Input)
                    % Calculate the index where to place the object in the array
                    idx = (VarIdx-1)*length(app.State.Input)+i;
                    Value = Values(i);
                    if ismissing(Value) || isempty(Value)
                        BackgroundColor = [255, 235, 238]./255;
                        FontColor = [183, 28, 28]./255;
                    else
                        BackgroundColor = [1, 1, 1];
                        FontColor = [0, 0, 0];
                    end
                    % Convert to text or extract contents from cell
                    if isnumeric(Value)
                        Value = num2str(Value);
                    elseif iscell(Value)
                        Value = Value{1};
                    end
                    % Render EditField
                    if app.ObjectExists('InputValue', idx)
                        % Object exists already, update the value only
                        UpdateProperty(app, app.Objects.InputValue(idx).Obj, 'Value', Value);
                        UpdateProperty(app, app.Objects.InputValue(idx).Obj, 'BackgroundColor', BackgroundColor);
                        UpdateProperty(app, app.Objects.InputValue(idx).Obj, 'FontColor', FontColor);
                    else
                        % Object does not exist, create
                        app.Objects.InputValue(idx).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                            'Value', Value, ...
                            'BackgroundColor', BackgroundColor, ...
                            'FontColor', FontColor, ...
                            'Editable', 'off');
                        app.Objects.InputValue(idx).Obj.Tag = num2str(VarIdx);
                        app.Objects.InputValue(idx).Obj.Layout.Row = 3+i;
                        app.Objects.InputValue(idx).Obj.Layout.Column = VarIdx+2;
                    end
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            for i = 50:-1:length(app.State.Model.Predictors)+1
                delete(findobj(app.DesignMatrixGridLayout, 'Tag', num2str(i)))
            end
        end
        % =========================================================
        % METHOD: Renders the stats panel header line
        function RenderStatsGridLayout(app)
            % Create one row in the gridlayout for each t contrast
            app.StatsGridLayout.RowHeight = [{24}, num2cell(ones(1, length(app.State.Statistics.Ttests.Titles)).*24)];
            app.StatsGridLayout.ColumnWidth = [{85, '3x'}, repmat({'3x'}, 1, length(app.State.Model.PredNames)), {'1x'}, repmat({'1x'}, 1, size(app.State.Statistics.Ftests, 2))];
        end
        % =========================================================
        % METHOD: Renders the stats panel header line
        function RenderStatsPanelHeader(app)
            % Create a label for each predictor, or remove if no longer in there.
            for i = 1:length(app.State.Model.PredNames)
                if app.ObjectExists('TContrastHeader', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.TContrastHeader(i).Obj, 'Text', app.State.Model.PredNames{i});
                else
                    % Object does not exist, create
                    app.Objects.TContrastHeader(i).Obj = uilabel(app.StatsGridLayout, ...
                        'Text', app.State.Model.PredNames{i}, ...
                        'FontSize', 10, ...
                        'HorizontalAlignment', 'center');
                    app.Objects.TContrastHeader(i).Obj.Tag = sprintf('Header-%i', i);
                    app.Objects.TContrastHeader(i).Obj.Layout.Row = 1;
                    app.Objects.TContrastHeader(i).Obj.Layout.Column = i+2; % Title and intercept are first 2
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            for i = 50:-1:length(app.State.Model.PredNames)+1
                delete(findobj(app.StatsGridLayout, 'Tag', sprintf('Header-%i', i)))
            end
        end
        % =========================================================
        % METHOD: Renders the T- and F-contrasts stats panel
        function RenderStatsPanel(app)
            % Regenerate for simpicity
            delete(findobj(app.StatsGridLayout, 'Tag', 'ContrastTitle'))
            delete(findobj(app.StatsGridLayout, 'Tag', 'ContrastValue'))
            delete(findobj(app.StatsGridLayout, 'Tag', 'FTestCheckbox'))
            % Create the contrast title input fields
            for i = 1:length(app.State.Statistics.Ttests.Titles)
                % Create input fields
                Obj = uieditfield(app.StatsGridLayout, 'text', ...
                    'Value', app.State.Statistics.Ttests.Titles{i}, ...
                    'ValueChangedFcn', createCallbackFcn(app, @TContrastTitleValueChanged, true));
                Obj.Tag = 'ContrastTitle';
                Obj.Layout.Row = i+1;
                Obj.Layout.Column = 1;
                Obj.UserData.idx = i;
            end
            % Create the contrast values input fields
            for i = 1:size(app.State.Statistics.Ttests.Contrasts, 1)
                % Create input fields
                for j = 1:size(app.State.Statistics.Ttests.Contrasts, 2)
                    Obj = uieditfield(app.StatsGridLayout, 'numeric', ...
                        'Value', app.State.Statistics.Ttests.Contrasts(i, j), ...
                        'ValueChangedFcn', createCallbackFcn(app, @TContrastValueChanged, true));
                    Obj.Tag = 'ContrastValue';
                    Obj.Layout.Row = i+1;
                    Obj.Layout.Column = j+1;
                    Obj.UserData.cIdx = i;
                    Obj.UserData.pIdx = j;
                end
            end
            for i = 1:size(app.State.Statistics.Ttests.Contrasts, 1)
                for j = 1:size(app.State.Statistics.Ftests, 2)
                    Obj = uicheckbox(app.StatsGridLayout, ...
                        'Value', app.State.Statistics.Ftests(i, j), ...
                        'Text', '', ...
                        'ValueChangedFcn', createCallbackFcn(app, @FTestValueChanged, true));
                    Obj.Tag = 'FTestCheckbox';
                    Obj.Layout.Row = i+1;
                    Obj.Layout.Column = j+size(app.State.Statistics.Ttests.Contrasts, 2)+2;
                    Obj.UserData.cIdx = i;
                    Obj.UserData.fIdx = j;
                end
            end
        end
        % =========================================================
        % METHOD: Renders the cluster alpha edit field
        function RenderClusterAlphaEditField(app)
            switch app.State.PostStats.Method
                case {'ClusterExtent', 'ClusterMass'}
                    UpdateProperty(app, app.ClusterAlphaLabel, 'Enable', 'on');
                    UpdateProperty(app, app.ClusterAlphaEditField, 'Enable', 'on');
                otherwise
                    UpdateProperty(app, app.ClusterAlphaLabel, 'Enable', 'off');
                    UpdateProperty(app, app.ClusterAlphaEditField, 'Enable', 'off');
            end
        end
        % =========================================================
        % APP FUNCTIONS: SETS THE APP STATE
        % ---------------------------------------------------------
        % FUNCTION: Sets loading state
        function SetLoading(app, isLoading)
            app.State.isLoading = isLoading;
            if isLoading
                app.LoadingImage.Visible = 'on';
                app.UIFigure.Pointer = 'watch';
                app.MainPanel.Enable = 'off';
                drawnow;
            else
                app.LoadingImage.Visible = 'off';
                app.UIFigure.Pointer = 'arrow';
                app.MainPanel.Enable = 'on';
            end
        end
        % =========================================================
        % CALLBACK FUNCTIONS
        % =========================================================
        function VarNameDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            % If the new variable is numeric, change to a continuous variable
            if isnumeric(app.State.Phenotype.(event.Value))
                Type = 'continuous';
                Standardize = 'demean';
            else % Change to a categorical variable
                Type = 'categorical';
                Standardize = 'n/a';
            end
            % Get the design matrix for this variable
            [DesMat, Levels] = app.GetVarDesMat(event.Value, Type, Standardize);
            if DesMat == -1
                % There was only one level in this variable. This is 
                % already captured by the intercept.
                % Reset the input and present a warning message.
                event.Source.Value = event.PreviousValue;
                sel = uiconfirm(app.UIFigure, 'This variable contains only one level. This is already modeled by the intercept. The predictor was not changed.', 'Warning', ...
                    'Options',{'OK'},...
                    'DefaultOption', 'OK', ...
                    'Icon', 'warning'); %#ok<NASGU>
                return
            end
            % Get how many levels there are now and how many there will be
            prevLevels = size(app.State.Model.Predictors(VarIdx).DesMat, 2);
            newLevels = size(DesMat, 2);
            % If the number of levels had changed, change the T contrasts
            if newLevels > prevLevels
                % Add columns of zeros to the contrast
                add = newLevels - prevLevels; % how many columns to add?
                col = size([app.State.Model.Predictors(1:VarIdx).DesMat], 2) + 1; % what columns index to add them to? +1 for intercept
                app.State.Statistics.Ttests.Contrasts = [...
                    app.State.Statistics.Ttests.Contrasts(:, 1:col), ...
                    zeros(size(app.State.Statistics.Ttests.Contrasts, 1), add), ...
                    app.State.Statistics.Ttests.Contrasts(:, col+1:end), ...
                    ];
            elseif newLevels < prevLevels
                % Remove columns from the contrast
                rm = prevLevels - newLevels; % how many columns to remove?
                col = size([app.State.Model.Predictors(1:VarIdx).DesMat], 2) + 1; % what columns index to remove them from? +1 for intercept
                app.State.Statistics.Ttests.Contrasts(:, col-rm+1:col) = [];
            end
            % Update the predictors's state
            app.State.Model.Predictors(VarIdx).Name = event.Value;
            app.State.Model.Predictors(VarIdx).Type = Type;
            app.State.Model.Predictors(VarIdx).Standardize = Standardize;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixModelSpec();
            app.RenderDesignMatrixVarNameInput();
            app.RenderDesignMatrixVarTypeInput();
            app.RenderDesignMatrixVarStandardizeInput();
            app.RenderDesignMatrixValueInput(VarIdx);
            app.RenderStatsGridLayout();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
        end
        % =========================================================
        function VarTypeDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = app.State.Model.Predictors(VarIdx).Name;
            % Set the standardize value 
            switch event.Value
                case 'continuous'
                    Standardize = 'demean';
                case 'categorical'
                    Standardize = 'n/a';
            end
            % Get the design matrix for this variable
            [DesMat, Levels] = app.GetVarDesMat(VarName, event.Value, Standardize);
            % Get how many levels there are now and how many there will be
            prevLevels = size(app.State.Model.Predictors(VarIdx).DesMat, 2);
            newLevels = size(DesMat, 2);
            % If the number of levels had changed, change the T contrasts
            if newLevels > prevLevels
                % Add columns of zeros to the contrast
                add = newLevels - prevLevels; % how many columns to add?
                col = size([app.State.Model.Predictors(1:VarIdx).DesMat], 2) + 1; % what columns index to add them to?
                app.State.Statistics.Ttests.Contrasts = [...
                    app.State.Statistics.Ttests.Contrasts(:, 1:col), ...
                    zeros(size(app.State.Statistics.Ttests.Contrasts, 1), add), ...
                    app.State.Statistics.Ttests.Contrasts(:, col+1:end), ...
                    ];
            elseif newLevels < prevLevels
                % Remove columns from the contrast
                rm = prevLevels - newLevels; % how many columns to remove?
                col = size([app.State.Model.Predictors(1:VarIdx).DesMat], 2) + 1; % what columns index to remove them from?
                app.State.Statistics.Ttests.Contrasts(:, col-rm+1:col) = [];
            end
            app.State.Model.Predictors(VarIdx).Type = event.Value;
            app.State.Model.Predictors(VarIdx).Standardize = Standardize;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixModelSpec();
            app.RenderDesignMatrixVarTypeInput();
            app.RenderDesignMatrixVarStandardizeInput();
            app.RenderDesignMatrixValueInput(VarIdx);
            app.RenderStatsGridLayout();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
        end
        % =========================================================
        function VarStandardizeDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = app.State.Model.Predictors(VarIdx).Name;
            VarType = app.State.Model.Predictors(VarIdx).Type;
            % Get the design matrix for this variable
            [DesMat, Levels] = app.GetVarDesMat(VarName, VarType, event.Value);
            app.State.Model.Predictors(VarIdx).Standardize = event.Value;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixValueInput(VarIdx);
        end
        % =========================================================
        function TContrastTitleValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            app.State.Statistics.Ttests.Titles{event.Source.UserData.idx} = event.Source.Value;
        end
        % =========================================================
        function TContrastValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            i = event.Source.UserData.cIdx;
            j = event.Source.UserData.pIdx;
            app.State.Statistics.Ttests.Contrasts(i, j) = event.Source.Value;
        end
        % =========================================================
        function FTestValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            i = event.Source.UserData.cIdx;
            j = event.Source.UserData.fIdx;
            app.State.Statistics.Ftests(i, j) = event.Source.Value;
            app.State.Statistics.Ftests
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FileList)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Add icons to components
            app.LoadingImage.ImageSource = 'loading.gif';
            app.OutputDirectoryBrowseButton.Icon = 'icon_browse.png';
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState(FileList);
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixSubjectInput();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app);
            end
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app);
            end
        end

        % Value changed function: DesignMatrixNumEVsInput
        function DesignMatrixNumEVsInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            if app.DesignMatrixNumEVsInput.Value > length(app.State.Model.Predictors)
                % ... the number of predictors increased
                % ---------------------------------------------------------
                % Check that there are variables in the data table
                VarNames = app.State.Phenotype.Properties.VariableNames;
                if length(VarNames) < 3
                    % No variables in the data table
                    event.Source.Value = event.PreviousValue;
                    sel = uiconfirm(app.UIFigure, 'There are no variables in the phenotype files. No predictor added.', 'Warning', ...
                        'Options',{'OK'},...
                        'DefaultOption', 'OK', ...
                        'Icon', 'warning'); %#ok<NASGU>
                    return
                end
                % Check if there are more than 2 levels in this variable, or select the next one
                SelIdx = 3; % The first 2 are the Participant ID and Dependent variable
                IsSel = false;
                while ~IsSel && SelIdx <= length(VarNames)
                    if isnumeric(app.State.Phenotype.(VarNames{SelIdx}))
                        [DesMat, Levels] = app.GetVarDesMat(VarNames{SelIdx}, 'continuous', 'demean');
                    else
                        [DesMat, Levels] = app.GetVarDesMat(VarNames{SelIdx}, 'categorical', 'demean');
                    end
                    if length(Levels) > 1
                        IsSel = true;
                    else
                        SelIdx = SelIdx + 1;
                    end
                end
                % If no selection could be made trow a warning and return
                if ~IsSel
                    % No variables in the data table had more than 1 level
                    event.Source.Value = event.PreviousValue;
                    sel = uiconfirm(app.UIFigure, 'There are no variables in the phenotype files with more than one level. No predictor added.', 'Warning', ...
                        'Options',{'OK'},...
                        'DefaultOption', 'OK', ...
                        'Icon', 'warning'); %#ok<NASGU>
                    return
                end
                % ---------------------------------------------------------
                % Get the label of the new term
                NewPred = app.State.Phenotype.Properties.VariableNames{SelIdx};
                % and the index of the new predictor
                VarIdx = length(app.State.Model.Predictors)+1;
                % and append the term to the predictors array
                app.State.Model.Predictors(VarIdx).Name = NewPred;
                % Check what type of variable it is, and set the state accordingly
                if iscell(app.State.Phenotype.(NewPred))
                    app.State.Model.Predictors(VarIdx).Type = 'categorical';
                    app.State.Model.Predictors(VarIdx).Standardize = 'n/a';
                else
                    app.State.Model.Predictors(VarIdx).Type = 'continuous';
                    app.State.Model.Predictors(VarIdx).Standardize = 'demean';
                end
                app.State.Model.Predictors(VarIdx).DesMat = DesMat;
                app.State.Model.Predictors(VarIdx).Levels = Levels;
                % ---------------------------------------------------------
                % Append the contrast vector with a zero for each level of this predictor
                app.State.Statistics.Ttests.Contrasts = [app.State.Statistics.Ttests.Contrasts, zeros(size(app.State.Statistics.Ttests.Contrasts, 1), size(DesMat, 2))];
            else
                % ---------------------------------------------------------
                % The number of predictors decreased, simply crop the predictors array
                VarIdx = 0;
                nLevels = size(app.State.Model.Predictors(end).DesMat, 2);
                app.State.Model.Predictors = app.State.Model.Predictors(1:app.DesignMatrixNumEVsInput.Value);
                % ---------------------------------------------------------
                % Remove the last column from the contrast vector of any existing contrasts
                app.State.Statistics.Ttests.Contrasts(:, end-nLevels+1:end) = [];
            end
            % Set the new model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixGridLayout();
            app.RenderDesignMatrixModelSpec();
            app.RenderDesignMatrixVarNameInput();
            app.RenderDesignMatrixVarTypeInput();
            app.RenderDesignMatrixVarStandardizeInput();
            app.RenderDesignMatrixValueInput(VarIdx);
            app.RenderStatsGridLayout();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
        end

        % Value changed function: TcontrastsSpinner
        function TcontrastsSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            value = event.Source.Value;
            if value < length(app.State.Statistics.Ttests.Titles)
                app.State.Statistics.Ttests.Titles = app.State.Statistics.Ttests.Titles(1:value);
                app.State.Statistics.Ttests.Contrasts = app.State.Statistics.Ttests.Contrasts(1:value, :);
                app.State.Statistics.Ftests = app.State.Statistics.Ftests(1:value, :);
            else
                % How many columns in the desmat?
                N = size([app.State.Model.Predictors.DesMat], 2) + 1; % intercept
                app.State.Statistics.Ttests.Titles{end+1} = sprintf('c%i', length(app.State.Statistics.Ttests.Titles)+1);
                app.State.Statistics.Ttests.Contrasts = [app.State.Statistics.Ttests.Contrasts; zeros(1, N)];
                app.State.Statistics.Ftests = [app.State.Statistics.Ftests; zeros(1, size(app.State.Statistics.Ftests, 2))];
            end
            % ---------------------------------------------------------
            % Render
            app.RenderStatsGridLayout();
            app.RenderStatsPanel();
        end

        % Value changed function: FtestsSpinner
        function FtestsSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            value = event.Source.Value;
            if value < size(app.State.Statistics.Ftests, 2)
                app.State.Statistics.Ftests = app.State.Statistics.Ftests(:, 1:value);
            else
                app.State.Statistics.Ftests = [app.State.Statistics.Ftests, zeros(length(app.State.Statistics.Ttests.Titles), 1)];
            end
            % ---------------------------------------------------------
            % Render
            app.RenderStatsGridLayout();
            app.RenderStatsPanel();
        end

        % Value changed function: ThresholdingMethodDropDown
        function ThresholdingMethodDropDownValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.Method = event.Source.Value;
            % ---------------------------------------------------------
            % Render
            app.RenderClusterAlphaEditField();
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.Alpha = event.Source.Value; 
        end

        % Value changed function: ClusterAlphaEditField
        function ClusterAlphaEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.ClusterAlpha = event.Source.Value; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'General Linear Model';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Enable = 'off';
            app.AddProcessButton.Text = 'Add Process';
            app.LoadingImage = uiimage(app.GridLayout);
            app.LoadingImage.Visible = 'off';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainGridLayout = uigridlayout(app.MainPanel);
            app.OutputDirectoryPanel = uipanel(app.MainGridLayout);
            app.OutputDirectoryPanel.Title = 'Output directory';
            app.OutputDirectoryGridLayout = uigridlayout(app.OutputDirectoryPanel);
            app.OutputDirectoryBrowseButton = uibutton(app.OutputDirectoryGridLayout, 'push');
            app.OutputDirectoryBrowseButton.Enable = 'off';
            app.OutputDirectoryBrowseButton.Text = '';
            app.OutputDirectoryValue = uieditfield(app.OutputDirectoryGridLayout, 'text');
            app.OutputDirectoryValue.Editable = 'off';
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.DesignMatrixTab = uitab(app.TabGroup);
            app.DesignMatrixTab.Title = 'Design Matrix';
            app.DesignMatrixTabGridLayout = uigridlayout(app.DesignMatrixTab);
            app.NumberofpredictorsLabel = uilabel(app.DesignMatrixTabGridLayout);
            app.NumberofpredictorsLabel.Text = 'Number of predictors';
            app.DesignMatrixNumEVsInput = uispinner(app.DesignMatrixTabGridLayout);
            app.DesignMatrixNumEVsInput.Limits = [0 50];
            app.DesignMatrixNumEVsInput.ValueChangedFcn = createCallbackFcn(app, @DesignMatrixNumEVsInputValueChanged, true);
            app.DesignMatrixPanel = uipanel(app.DesignMatrixTabGridLayout);
            app.DesignMatrixGridLayout = uigridlayout(app.DesignMatrixPanel);
            app.DesignMatrixVarNameLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixVarNameLabel.Text = 'Variable';
            app.DesignMatrixVarTypeLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixVarTypeLabel.Text = 'Variable type';
            app.DesignMatrixInterceptEditField = uieditfield(app.DesignMatrixGridLayout, 'text');
            app.DesignMatrixInterceptEditField.Editable = 'off';
            app.DesignMatrixInterceptEditField.Value = 'Intercept';
            app.ModelSpecLabel = uilabel(app.DesignMatrixTabGridLayout);
            app.ModelSpecLabel.Text = 'Model specification';
            app.ModelSpecValue = uilabel(app.DesignMatrixTabGridLayout);
            app.ModelSpecValue.Text = 'Y ~ 1';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Statistics';
            app.StatsTabGridLayout = uigridlayout(app.StatsTab);
            app.TcontrastsSpinnerLabel = uilabel(app.StatsTabGridLayout);
            app.TcontrastsSpinnerLabel.Text = 'T-contrasts';
            app.TcontrastsSpinner = uispinner(app.StatsTabGridLayout);
            app.TcontrastsSpinner.Limits = [0 50];
            app.TcontrastsSpinner.ValueChangedFcn = createCallbackFcn(app, @TcontrastsSpinnerValueChanged, true);
            app.FtestsSpinnerLabel = uilabel(app.StatsTabGridLayout);
            app.FtestsSpinnerLabel.Text = 'F-tests';
            app.FtestsSpinner = uispinner(app.StatsTabGridLayout);
            app.FtestsSpinner.Limits = [0 50];
            app.FtestsSpinner.ValueChangedFcn = createCallbackFcn(app, @FtestsSpinnerValueChanged, true);
            app.StatsPanel = uipanel(app.StatsTabGridLayout);
            app.StatsGridLayout = uigridlayout(app.StatsPanel);
            app.TitleLabel = uilabel(app.StatsGridLayout);
            app.TitleLabel.Text = 'Title';
            app.InterceptLabel = uilabel(app.StatsGridLayout);
            app.InterceptLabel.Text = 'Intercept';
            app.PostStatsTab = uitab(app.TabGroup);
            app.PostStatsTab.Title = 'Post-statistics';
            app.PostStatsGridLayout = uigridlayout(app.PostStatsTab);
            app.PrethresholdmaskingEditFieldLabel = uilabel(app.PostStatsGridLayout);
            app.PrethresholdmaskingEditFieldLabel.Enable = 'off';
            app.PrethresholdmaskingEditFieldLabel.Text = 'Pre-threshold masking';
            app.PrethresholdmaskingEditField = uieditfield(app.PostStatsGridLayout, 'text');
            app.PrethresholdmaskingEditField.Enable = 'off';
            app.ChannelLocationsBrowseButton = uibutton(app.PostStatsGridLayout, 'push');
            app.ChannelLocationsBrowseButton.Enable = 'off';
            app.ChannelLocationsBrowseButton.Text = '';
            app.ThresholdingMethodPanel = uipanel(app.PostStatsGridLayout);
            app.ThresholdingMethodPanel.Title = 'Thresholding method';
            app.ThresholdingMethodGridLayout = uigridlayout(app.ThresholdingMethodPanel);
            app.ThresholdingMethodDropDown = uidropdown(app.ThresholdingMethodGridLayout);
            app.ThresholdingMethodDropDown.Items = {'Uncorrected', 'False Discovery Rate', 'Cluster extent permutations', 'Cluster mass permutations', 'TFCE permutations'};
            app.ThresholdingMethodDropDown.ItemsData = {'Uncorrected', 'FDR', 'ClusterExtent', 'ClusterMass', 'TFCE'};
            app.ThresholdingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingMethodDropDownValueChanged, true);
            app.ThresholdingMethodDropDown.Value = 'TFCE';
            app.AlphaEditFieldLabel = uilabel(app.ThresholdingMethodGridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.05;
            app.ClusterAlphaEditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.ClusterAlphaEditField.Limits = [0 1];
            app.ClusterAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterAlphaEditFieldValueChanged, true);
            app.ClusterAlphaEditField.Enable = 'off';
            app.ClusterAlphaEditField.Value = 0.05;
            app.ClusterAlphaLabel = uilabel(app.ThresholdingMethodGridLayout);
            app.ClusterAlphaLabel.Enable = 'off';
            app.ClusterAlphaLabel.Text = 'Cluster alpha';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralLinearModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end